classdef mainInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NmerodepalavrascorretasLabel  matlab.ui.control.Label
        palavrasErradas               matlab.ui.control.NumericEditField
        ComputarButton                matlab.ui.control.Button
        ExecutarButton                matlab.ui.control.Button
        ProximoButton                 matlab.ui.control.Button
        StatusBox                     matlab.ui.container.Panel
        udioemexecuoTextAreaLabel     matlab.ui.control.Label
        fraseExecucao                 matlab.ui.control.TextArea
        StatusLampLabel               matlab.ui.control.Label
        StatusLamp                    matlab.ui.control.Lamp
        VerdeudioexecutandoVermelhoOuvintepronunciandoLabel  matlab.ui.control.Label
        Canaisativos                  matlab.ui.container.ButtonGroup
        Esquerda                      matlab.ui.control.RadioButton
        Direita                       matlab.ui.control.RadioButton
        EsquerdaDireita               matlab.ui.control.RadioButton
        Saida                         matlab.ui.container.ButtonGroup
        IC                            matlab.ui.control.RadioButton
        Normoouvintes                 matlab.ui.control.RadioButton
        Pannel1                       matlab.ui.container.Panel
        AdicionarlistaButton          matlab.ui.control.Button
        listBoxOrdemExec              matlab.ui.control.ListBox
        nomeLista                     matlab.ui.control.Label
        FimButton                     matlab.ui.control.Button
        NmerototaldepalavrasEditFieldLabel  matlab.ui.control.Label
        totalPalavras                 matlab.ui.control.NumericEditField
        relacaoPalavrasCorretas       matlab.ui.container.ButtonGroup
        Button1                       matlab.ui.control.ToggleButton
        Button2                       matlab.ui.control.ToggleButton
        Button3                       matlab.ui.control.ToggleButton
        Button4                       matlab.ui.control.ToggleButton
        Button5                       matlab.ui.control.ToggleButton
        Button6                       matlab.ui.control.ToggleButton
        Button7                       matlab.ui.control.ToggleButton
        Button8                       matlab.ui.control.ToggleButton
    end

    
    properties (Access = public)
        FolderFullPath % Description
        Resultados % Description
        NumTotalPalavras % Description
        acertoPercentual % Description
        acertoPercentualUp  % Description
        acertoPercentualDw  % Description
        snrVec % Description
        NumPalavrasFrase % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
        end

        % Button pushed function: AdicionarlistaButton
        function AdicionarlistaButtonPushed(app, event)
            % Seleciona pasta via UI e lê arquivos .WAV
            folderPath = uigetdir('','Selecione pasta com arquivos .wav da lista de sentenças.');
            cd(folderPath);
            addpath(genpath(folderPath));
            app.FolderFullPath = folderPath;
            listaAudios = dir('*.wav');
            
            % Trick para não minimizar app quando rodar uigetdir()
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            % Ordenar audios:
            % Arquivos fala femininas  (F), SNR-8 to SNR12
            % Arquivos fala masculinas (M), SNR12 to SNR-8
            
            % Atualiza listBox com arquivos WAV da pasta selecionada
            app.listBoxOrdemExec.Items = {listaAudios.name};
            app.listBoxOrdemExec.ItemsData = 1:length({listaAudios.name});
            % Seleciona primeiro audio para execução
            app.listBoxOrdemExec.Value = 1;
            % Lê nome da pasta e plccxota na interface gráfica para controle
            [filepath,FolderName,ext] = fileparts(folderPath);
            app.nomeLista.Text = FolderName;
            
            % Cria vetor de resultados (adaptar para aplicação mais útil)
            app.Resultados = zeros(length({listaAudios.name}),1);
            app.NumTotalPalavras = zeros(length({listaAudios.name}),1);
            
            % Atualiza permissoes botoes
            app.ExecutarButton.Enable = 'on';
            
            
        end

        % Button pushed function: ComputarButton
        function ComputarButtonPushed(app, event)
            % Habilita troca para próximo audio
            app.ProximoButton.Enable = 'on';
            app.Resultados(app.listBoxOrdemExec.Value) = app.palavrasErradas.Value;
            app.NumTotalPalavras(app.listBoxOrdemExec.Value) = app.totalPalavras.Value;
            fprintf('Palavras corretas para audio %s = %.0f \n',app.listBoxOrdemExec.Items{app.listBoxOrdemExec.Value},app.palavrasErradas.Value)            
        end

        % Button pushed function: ExecutarButton
        function ExecutarButtonPushed(app, event)
            % Carrega dados do arquivo
            fileName = app.listBoxOrdemExec.Items(app.listBoxOrdemExec.Value);
            fullFileName = fullfile(app.FolderFullPath,fileName);
                       
            % Le audio e metadata
            [signal, fs] = audioread(fullFileName{1});
            audioInformations = audioinfo(fullFileName{1});
            
            % Implementar metadata (fala completa) na seção 'Comment' do arquivo wav
            % Para isto: audiowrite(filename,y,fs,'Comment','frase por extenso')
            % app.fraseExecucao.Value = fileName; 
            % app.fraseExecucao.Value = audioInformations.Title; 
            app.fraseExecucao.Value = audioInformations.Title; 
            
            % Numero de palavras na frase
            app.NumPalavrasFrase = strsplit(audioInformations.Title);
            app.totalPalavras.Value = length(app.NumPalavrasFrase);
            
%             for k=1:length(app.NumPalavrasFrase)
%                 app.Button1
%             end
            
            % Adicionar a app.relacaoPalavrasCorretas as palavras da frase, em cada Button
            % app.relacaoPalavrasCorretas.
            
            if app.Normoouvintes.Value == 1
                % Reproducao do sinal - A ser adaptada para comunicar com CCi-Mobile
                player = audioplayer(signal,fs);
                player.play         
                
            elseif app.IC.Value == 1
                
                % Call function 'sendStreamer(signal)' - ONLY WHEN HAVE INTERFACE
                % sendStreamer(fullFileName{1});                    
                disp('SENT TO IC!')                
            end
                        
            % Atualiza permissões e luz de status
            app.StatusLamp.Color = 'green';
            pause(length(signal)/fs) % intervalo para reprodução
            app.StatusLamp.Color = 'red';
            app.ComputarButton.Enable = 'on';
        end

        % Button pushed function: ProximoButton
        function ProximoButtonPushed(app, event)
            % Atualiza contador de lista
            app.listBoxOrdemExec.Value = app.listBoxOrdemExec.Value + 1;
            % Desabilita botão 'proximo audio' até reprodução do audio atual
            app.ProximoButton.Enable = 'off';
            app.ComputarButton.Enable = 'off';            
        end

        % Button pushed function: FimButton
        function FimButtonPushed(app, event)
            
            assignin ('base','resultadoTrial',app.Resultados);
            
            % Relação de acertos percentuais
            app.acertoPercentual = app.Resultados./app.NumTotalPalavras;
            
            app.acertoPercentualUp = app.acertoPercentual(1:13);
            app.acertoPercentualDw = flip(app.acertoPercentual(14:end));
            
            % Plot grafico relativo ao acerto percentual
            fig = uifigure;
            ax = uiaxes(fig); 
            x = [-20; -8; -6; -4; -2; 0; 2; 4; 6; 8; 10; 12; 30];
            plot(ax, x, app.acertoPercentualUp,'r*');
            hold(ax,'on');
            plot(ax, x, app.acertoPercentualDw,'b*');
            ax.Title.String = 'Relação percentual de acertos';
            ax.XLabel.String = 'dB';
            ax.XLabel.FontSize = 12;
            ax.YLabel.String = 'Acertos(%)';
            ax.YLabel.FontSize = 12;
            
            % Salva dados em .mat, na respectiva pasta dos audios aplicados
            save resultadoTrial
            app.delete
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.NmerodepalavrascorretasLabel = uilabel(app.UIFigure);
            app.NmerodepalavrascorretasLabel.Text = 'Número de palavras corretas';
            app.palavrasErradas = uieditfield(app.UIFigure, 'numeric');
            app.ComputarButton = uibutton(app.UIFigure, 'push');
            app.ComputarButton.ButtonPushedFcn = createCallbackFcn(app, @ComputarButtonPushed, true);
            app.ComputarButton.Enable = 'off';
            app.ComputarButton.Text = 'Computar';
            app.ExecutarButton = uibutton(app.UIFigure, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Enable = 'off';
            app.ExecutarButton.Text = 'Executar';
            app.ProximoButton = uibutton(app.UIFigure, 'push');
            app.ProximoButton.ButtonPushedFcn = createCallbackFcn(app, @ProximoButtonPushed, true);
            app.ProximoButton.Enable = 'off';
            app.ProximoButton.Text = 'Próximo';
            app.StatusBox = uipanel(app.UIFigure);
            app.StatusBox.Title = 'Status de execução';
            app.udioemexecuoTextAreaLabel = uilabel(app.StatusBox);
            app.udioemexecuoTextAreaLabel.Text = 'Áudio em execução';
            app.fraseExecucao = uitextarea(app.StatusBox);
            app.StatusLampLabel = uilabel(app.StatusBox);
            app.StatusLampLabel.Text = 'Status: ';
            app.StatusLamp = uilamp(app.StatusBox);
            app.VerdeudioexecutandoVermelhoOuvintepronunciandoLabel = uilabel(app.StatusBox);
            app.VerdeudioexecutandoVermelhoOuvintepronunciandoLabel.Text = {'Verde  - Áudio executando '; 'Vermelho   -  Ouvinte pronunciando'};
            app.Canaisativos = uibuttongroup(app.StatusBox);
            app.Canaisativos.Title = 'Canais ativos (IC)';
            app.Esquerda = uiradiobutton(app.Canaisativos);
            app.Esquerda.Text = 'Esquerda';
            app.Esquerda.Value = true;
            app.Direita = uiradiobutton(app.Canaisativos);
            app.Direita.Text = 'Direita';
            app.EsquerdaDireita = uiradiobutton(app.Canaisativos);
            app.EsquerdaDireita.Text = 'Esquerda / Direita';
            app.Saida = uibuttongroup(app.StatusBox);
            app.Saida.Title = 'Saida';
            app.IC = uiradiobutton(app.Saida);
            app.IC.Text = 'IC';
            app.IC.Value = true;
            app.Normoouvintes = uiradiobutton(app.Saida);
            app.Normoouvintes.Text = 'Normo-ouvintes';
            app.Pannel1 = uipanel(app.UIFigure);
            app.Pannel1.Title = 'Ordem de execução dos áudios';
            app.AdicionarlistaButton = uibutton(app.Pannel1, 'push');
            app.AdicionarlistaButton.ButtonPushedFcn = createCallbackFcn(app, @AdicionarlistaButtonPushed, true);
            app.AdicionarlistaButton.Text = 'Adicionar lista';
            app.listBoxOrdemExec = uilistbox(app.Pannel1);
            app.listBoxOrdemExec.Items = {};
            app.listBoxOrdemExec.Enable = 'off';
            app.listBoxOrdemExec.Value = {};
            app.nomeLista = uilabel(app.Pannel1);
            app.nomeLista.Text = '';
            app.FimButton = uibutton(app.UIFigure, 'push');
            app.FimButton.ButtonPushedFcn = createCallbackFcn(app, @FimButtonPushed, true);
            app.FimButton.Text = 'Fim';
            app.NmerototaldepalavrasEditFieldLabel = uilabel(app.UIFigure);
            app.NmerototaldepalavrasEditFieldLabel.Text = 'Número total de palavras';
            app.totalPalavras = uieditfield(app.UIFigure, 'numeric');
            app.totalPalavras.Editable = 'off';
            app.relacaoPalavrasCorretas = uibuttongroup(app.UIFigure);
            app.relacaoPalavrasCorretas.Title = 'Relação de palavras corretas';
            app.Button1 = uitogglebutton(app.relacaoPalavrasCorretas);
            app.Button1.Text = 'Button1';
            app.Button1.Value = true;
            app.Button2 = uitogglebutton(app.relacaoPalavrasCorretas);
            app.Button2.Text = 'Button2';
            app.Button3 = uitogglebutton(app.relacaoPalavrasCorretas);
            app.Button3.Text = 'Button3';
            app.Button4 = uitogglebutton(app.relacaoPalavrasCorretas);
            app.Button4.Text = 'Button4';
            app.Button5 = uitogglebutton(app.relacaoPalavrasCorretas);
            app.Button5.Text = 'Button5';
            app.Button6 = uitogglebutton(app.relacaoPalavrasCorretas);
            app.Button6.Text = 'Button6';
            app.Button7 = uitogglebutton(app.relacaoPalavrasCorretas);
            app.Button7.Text = 'Button7';
            app.Button8 = uitogglebutton(app.relacaoPalavrasCorretas);
            app.Button8.Text = 'Button8';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mainInterface()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end